classdef IMP_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PutthevaluestoimpcontrolEditField  matlab.ui.control.EditField
        PutthevaluestoextarctImpedencecontrolLabel  matlab.ui.control.Label
        GenerateImpedenceControlButton  matlab.ui.control.Button
        remove                          matlab.ui.control.Button
        Add                             matlab.ui.control.Button
        IMP_Control_table               matlab.ui.control.Table
        IMPEDENCECONTROLLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        dhs; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
             %app.dhs = dh_parameters;
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            data = app.IMP_Control_table.Data;
            inputstr = app.PutthevaluestoimpcontrolEditField.Value;
            vals = strsplit(inputstr, ' ');
            for i = 1:8
                newRow{1,i} = str2double(vals{1,i});
            end 
            % Append the new row to the data
            newData = [data; newRow];

            % Update the table with the new data
            app.IMP_Control_table.Data = newData;
        end

        % Button pushed function: remove
        function removeButtonPushed(app, event)
            currentData = app.IMP_Control_table.Data;

            % Check if there are rows in the table
            if ~isempty(currentData)
                % Remove the last row from the data
                updatedData = currentData(1:end-1, :);
                
                % Update the UITable data
                app.IMP_Control_table.Data = updatedData;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IMPEDENCECONTROLLabel = uilabel(app.UIFigure);
            app.IMPEDENCECONTROLLabel.Text = 'IMPEDENCE CONTROL';
            app.IMP_Control_table = uitable(app.UIFigure);
            app.IMP_Control_table.ColumnName = {'L'; 'Kr'; 'ml'; 'mm'; 'iL'; 'im'; 'K_D'; 'K_P'};
            app.IMP_Control_table.RowName = {};
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.remove = uibutton(app.UIFigure, 'push');
            app.remove.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.remove.Text = '-';
            app.GenerateImpedenceControlButton = uibutton(app.UIFigure, 'push');
            app.GenerateImpedenceControlButton.Text = {'Generate'; 'Impedence Control'};
            app.PutthevaluestoextarctImpedencecontrolLabel = uilabel(app.UIFigure);
            app.PutthevaluestoextarctImpedencecontrolLabel.Text = {'Put the values to'; ' extarct Impedence control'};
            app.PutthevaluestoimpcontrolEditField = uieditfield(app.UIFigure, 'text');
            app.PutthevaluestoimpcontrolEditField.Value = '0.5 110 50 50 11 0.03 200 450';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMP_control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end